classdef MyPumpGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        COMPortDropDownLabel      matlab.ui.control.Label
        COMPortDropDown           matlab.ui.control.DropDown
        baudrateDropDownLabel     matlab.ui.control.Label
        baudrateDropDown          matlab.ui.control.DropDown
        pumpnumDropDownLabel      matlab.ui.control.Label
        pumpnumDropDown           matlab.ui.control.DropDown
        rateunitsDropDownLabel    matlab.ui.control.Label
        rateunitsDropDown         matlab.ui.control.DropDown
        ratesEditFieldLabel       matlab.ui.control.Label
        ratesEditField            matlab.ui.control.EditField
        steptimesEditFieldLabel   matlab.ui.control.Label
        steptimesEditField        matlab.ui.control.NumericEditField
        stepdelaysEditFieldLabel  matlab.ui.control.Label
        stepdelaysEditField       matlab.ui.control.NumericEditField
        startButton               matlab.ui.control.Button
        stopButton                matlab.ui.control.Button
        applyButton               matlab.ui.control.Button
        diameterEditFieldLabel    matlab.ui.control.Label
        diameterEditField         matlab.ui.control.NumericEditField
        runningLabel              matlab.ui.control.Label
        ERRORLabel                matlab.ui.control.Label
        quitButton                matlab.ui.control.Button
        ratelimEditFieldLabel     matlab.ui.control.Label
        ratelimEditField          matlab.ui.control.EditField
        vollimEditFieldLabel      matlab.ui.control.Label
        vollimEditField           matlab.ui.control.EditField
        ratesTextAreaLabel        matlab.ui.control.Label
        ratesTextArea             matlab.ui.control.TextArea
        volumeTextAreaLabel       matlab.ui.control.Label
        volumeTextArea            matlab.ui.control.TextArea
        stepsEditFieldLabel       matlab.ui.control.Label
        stepsEditField            matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        pump
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.applyButton.Enable = 1;
            app.startButton.Enable = 0;
            app.stopButton.Enable = 0;
        end

        % Button pushed function: startButton
        function startButtonPushed(app, event)
            
            app.pump.start()
            
            
        end

        % Button pushed function: applyButton
        function applyButtonPushed(app, event)
            app.runningLabel.Visible = 1;
            app.ERRORLabel.Visible = 0;
            app.startButton.Enable = 0;
            app.stopButton.Enable = 0;

            drawnow;
            try
                if ~isempty(app.pump)
                    app.pump.close()
                end
                
                app.pump=Pump(app.COMPortDropDown.Value,str2num(app.baudrateDropDown.Value),str2num(app.pumpnumDropDown.Value),app.diameterEditField.Value);
            
                app.pump.set_units('uL/min')
                
                rates=eval(app.ratesEditField.Value);
                
                times=repmat(app.steptimesEditField.Value,[1,length(rates)]);
                delays=repmat(app.stepdelaysEditField.Value,[1,length(rates)]);
                
                volumes=times.*rates/60;
                
                app.pump.set_volume(volumes)
                app.pump.set_rate(rates)
                app.pump.set_delay(delays)

                app.ratesTextArea.Value=num2str(rates);
                app.volumeTextArea.Value=num2str(volumes);
                
                limits = str2num(app.pump.limits());
                
                app.vollimEditField.Value = [num2str(round(limits(3))) ' ' num2str(limits(4))];
                app.ratelimEditField.Value = [num2str(round(limits(1))) ' ' num2str(limits(2))];
                
                app.stepsEditField.Value=length(rates);
                
                app.startButton.Enable = 1;
                app.stopButton.Enable = 1;
            catch
                app.ERRORLabel.Visible = 1;
            end
            
            
            app.runningLabel.Visible = 0;
            drawnow;

        end

        % Button pushed function: stopButton
        function stopButtonPushed(app, event)
            app.pump.stop()
        end

        % Callback function
        function pauseButtonPushed(app, event)
            app.pump.pause()
        end

        % Button pushed function: quitButton
        function quitButtonPushed(app, event)
            app.pump.close()
            delete(app.UIFigure)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.COMPortDropDownLabel = uilabel(app.UIFigure);
            app.COMPortDropDownLabel.Text = 'COM Port';
            app.COMPortDropDown = uidropdown(app.UIFigure);
            app.COMPortDropDown.Items = {'COM1', 'COM2', 'COM3', 'COM4', 'COM5'};
            app.COMPortDropDown.Value = 'COM3';
            app.baudrateDropDownLabel = uilabel(app.UIFigure);
            app.baudrateDropDownLabel.Text = 'baud rate';
            app.baudrateDropDown = uidropdown(app.UIFigure);
            app.baudrateDropDown.Items = {'115200', '9600', ''};
            app.baudrateDropDown.Value = '115200';
            app.pumpnumDropDownLabel = uilabel(app.UIFigure);
            app.pumpnumDropDownLabel.Text = 'pump num';
            app.pumpnumDropDown = uidropdown(app.UIFigure);
            app.pumpnumDropDown.Items = {'1', '2'};
            app.pumpnumDropDown.Value = '1';
            app.rateunitsDropDownLabel = uilabel(app.UIFigure);
            app.rateunitsDropDownLabel.Text = 'rate units';
            app.rateunitsDropDown = uidropdown(app.UIFigure);
            app.rateunitsDropDown.Items = {'uL/min', 'mL/min'};
            app.rateunitsDropDown.Value = 'uL/min';
            app.ratesEditFieldLabel = uilabel(app.UIFigure);
            app.ratesEditFieldLabel.Text = 'rates';
            app.ratesEditField = uieditfield(app.UIFigure, 'text');
            app.ratesEditField.Value = '64*ones(1,4)*12.98';
            app.steptimesEditFieldLabel = uilabel(app.UIFigure);
            app.steptimesEditFieldLabel.Text = 'step time (s)';
            app.steptimesEditField = uieditfield(app.UIFigure, 'numeric');
            app.steptimesEditField.Value = 10;
            app.stepdelaysEditFieldLabel = uilabel(app.UIFigure);
            app.stepdelaysEditFieldLabel.Text = 'step delay (s)';
            app.stepdelaysEditField = uieditfield(app.UIFigure, 'numeric');
            app.stepdelaysEditField.Value = 10;
            app.startButton = uibutton(app.UIFigure, 'push');
            app.startButton.ButtonPushedFcn = createCallbackFcn(app, @startButtonPushed, true);
            app.startButton.Text = 'start';
            app.stopButton = uibutton(app.UIFigure, 'push');
            app.stopButton.ButtonPushedFcn = createCallbackFcn(app, @stopButtonPushed, true);
            app.stopButton.Text = 'stop';
            app.applyButton = uibutton(app.UIFigure, 'push');
            app.applyButton.ButtonPushedFcn = createCallbackFcn(app, @applyButtonPushed, true);
            app.applyButton.Text = 'apply';
            app.diameterEditFieldLabel = uilabel(app.UIFigure);
            app.diameterEditFieldLabel.Text = 'diameter';
            app.diameterEditField = uieditfield(app.UIFigure, 'numeric');
            app.diameterEditField.Value = 27;
            app.runningLabel = uilabel(app.UIFigure);
            app.runningLabel.Visible = 'off';
            app.runningLabel.Text = 'running....';
            app.ERRORLabel = uilabel(app.UIFigure);
            app.ERRORLabel.Visible = 'off';
            app.ERRORLabel.Text = 'ERROR';
            app.quitButton = uibutton(app.UIFigure, 'push');
            app.quitButton.ButtonPushedFcn = createCallbackFcn(app, @quitButtonPushed, true);
            app.quitButton.Text = 'quit';
            app.ratelimEditFieldLabel = uilabel(app.UIFigure);
            app.ratelimEditFieldLabel.Text = 'rate lim';
            app.ratelimEditField = uieditfield(app.UIFigure, 'text');
            app.ratelimEditField.Editable = 'off';
            app.vollimEditFieldLabel = uilabel(app.UIFigure);
            app.vollimEditFieldLabel.Text = 'vol lim';
            app.vollimEditField = uieditfield(app.UIFigure, 'text');
            app.vollimEditField.Editable = 'off';
            app.ratesTextAreaLabel = uilabel(app.UIFigure);
            app.ratesTextAreaLabel.Text = 'rates';
            app.ratesTextArea = uitextarea(app.UIFigure);
            app.volumeTextAreaLabel = uilabel(app.UIFigure);
            app.volumeTextAreaLabel.Text = 'volume';
            app.volumeTextArea = uitextarea(app.UIFigure);
            app.stepsEditFieldLabel = uilabel(app.UIFigure);
            app.stepsEditFieldLabel.Text = 'steps';
            app.stepsEditField = uieditfield(app.UIFigure, 'numeric');
            app.stepsEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MyPumpGui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end